classdef AAA_Iter5_Load < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        EditField             matlab.ui.control.EditField
        BrowseDataButton      matlab.ui.control.Button
        MeasurementNameLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AAA_Iter5_app; % Description
    end
    
    methods (Access = private)
        
        function change_check_boxes_visibility(app, bool)

            app.AAA_Iter5_app.Panel_7.Visible = bool; 
            app.AAA_Iter5_app.GridLayout10.Visible = bool; 
            app.AAA_Iter5_app.DefaultCheckBox1.Enable = bool; 
            app.AAA_Iter5_app.DefaultCheckBox2.Enable = bool; 
            app.AAA_Iter5_app.DefaultCheckBox3.Enable = bool; 
            app.AAA_Iter5_app.DefaultCheckBox4.Enable = bool; 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.AAA_Iter5_app = caller; 

            % Get the screen size
            screensize = get(groot, 'ScreenSize');

            % Calculate the new position for the figure
            appwidth = 500;
            appheight = 250;
            appx = (screensize(3) - appwidth) / 2;
            appy = (screensize(4) - appheight) / 2;

            % Set the new position for the figure
            set(app.UIFigure, 'Position', [appx, appy, appwidth, appheight]);
        end

        % Button pushed function: BrowseDataButton
        function BrowseDataButtonPushed(app, event)
            
            data_value = app.AAA_Iter5_app.number_data_loaded_in;
            app.AAA_Iter5_app.stored_tab_data(data_value).LOAD_MAT_FILE;

            app.AAA_Iter5_app.stored_tab_data(data_value).selected_title = app.EditField.Value;
            title_data = app.EditField.Value;
            app.AAA_Iter5_app.filename_data_arr(data_value) = cellstr(title_data);
            app.AAA_Iter5_app.ListBox.Items = app.AAA_Iter5_app.filename_data_arr;
            app.AAA_Iter5_app.list_box_index = app.AAA_Iter5_app.list_box_index +1; 


            progress_bar = uiprogressdlg(app.UIFigure, 'Title', 'B&W Active Absorber App', 'Message', 'Loading Data', 'Indeterminate','on'); 


            app.AAA_Iter5_app.number_data_loaded_in = app.AAA_Iter5_app.number_data_loaded_in + 1; 
            pause(1); 
            close(progress_bar); 
            change_check_boxes_visibility(app, true);
            delete(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.MeasurementNameLabel = uilabel(app.GridLayout2);
            app.MeasurementNameLabel.Text = 'Measurement Name:';
            app.BrowseDataButton = uibutton(app.GridLayout2, 'push');
            app.BrowseDataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseDataButton.Text = 'Browse Data';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAA_Iter5_Load(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end